classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        wynikiubytkusuchuButton        matlab.ui.control.Button
        UbytekpraweuchoEditField       matlab.ui.control.NumericEditField
        UbytekpraweuchoEditFieldLabel  matlab.ui.control.Label
        UbytekleweuchoEditField        matlab.ui.control.NumericEditField
        UbytekleweuchoEditFieldLabel   matlab.ui.control.Label
        KalibracjaButton_2             matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        Prawa                          matlab.ui.control.RadioButton
        Lewa                           matlab.ui.control.RadioButton
        Panel1                         matlab.ui.container.Panel
        WzmocnienieListBox             matlab.ui.control.ListBox
        WzmocnienieListBoxLabel        matlab.ui.control.Label
        CzstotliwoListBox              matlab.ui.control.ListBox
        CzstotliwoListBoxLabel         matlab.ui.control.Label
        label1                         matlab.ui.control.Label
        OdtwrzdzwikButton              matlab.ui.control.Button
        TakButton                      matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       tab1=[0, 0, 0];
       wynikL = 0;
       wynikP = 0;
       tab2=[0,0,0];


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%           selectedButton = app.ButtonGroup.SelectedObject;
                    
            V1 = app.Lewa.Value
            V2 = app.Prawa.Value

            if V1 == 1
                app.OdtwrzdzwikButton;
                
            elseif V2 == 1
                app.OdtwrzdzwikButton;
            end

        end

        % Button pushed function: OdtwrzdzwikButton
        function OdtwrzdzwikButtonPushed(app, event)
%generacja sygnału
% oczytanie wartości częstotliwości sygnału z listboxa
            item = app.CzstotliwoListBox.Value;
            item2 = app.WzmocnienieListBox.Value;
% zmiana  zmiennej ze stringa na numric            
            f = str2num(item);
            Fs = 48000;
            t=0:1/Fs:1;
                        
            A = str2num(item2);
            s =1*sin(2*pi*t*f);
            
            coef=10.^(A/20);
            ss=s.*coef;
             
%odczytanie która słuchawka jest załączona
            if (app.ButtonGroup.Visible)
                if (app.Lewa.Value)
                Out = [ss; zeros(size(t))]';
                elseif(app.Prawa.Value)
                Out = [zeros(size(t)); ss]';    
                end                
            end
%odtworzenie dzwięku         
            sound(Out,Fs);


        end

        % Button pushed function: TakButton
        function TakButtonPushed(app, event)
            item = app.CzstotliwoListBox.Value;
            f = str2num(item);
            item2 = app.WzmocnienieListBox.Value;
            db = str2num(item2);
           
            if (app.ButtonGroup.Visible)
                if (app.Lewa.Value)
                    plot(app.UIAxes,f,db,"Marker","*","Color",'b',"LineStyle",":")
                elseif(app.Prawa.Value)
                    plot(app.UIAxes,f,db,"Marker","o","Color",'r',"LineStyle","--")   
                end                
            end
          if (app.Lewa.Value == 1)  
            if (f == 500)
                app.tab1(1) = db;            
            elseif(f == 1000)
                app.tab1(2) = db;
            elseif(f == 2000)
                app.tab1(3) = db;
            end
          else   
            if (f == 500)
                app.tab2(1) = db;            
            elseif(f == 1000)
                app.tab2(2) = db;
            elseif(f == 2000)
                app.tab2(3) = db;
            end
          end     
            
            
        end

        % Button pushed function: KalibracjaButton_2
        function KalibracjaButton_2Pushed(app, event)
            fs=48000;
            f=1000;
            t=0:1/fs:1;
            A=1;
            y=A*sin(2*pi*f*t);
            Py=10*log10(sum(y.^2));
            coef=10.^(5/20);
            yy=y.*coef;
            Pyy=10*log10(sum(yy.^2));
            zz=[];
            for i=1:10
                z1=y(1,1:48000);
                z2=yy(1,1:48000);
                zz=[zz z1 z2]; 
            end
            z4=zz/max(abs(zz));
            plot(z4);
            
            soundsc(z4,fs);
        end

        % Value changed function: UbytekleweuchoEditField
        function UbytekleweuchoEditFieldValueChanged(app, event)
                       
          
        end

        % Button pushed function: wynikiubytkusuchuButton
        function wynikiubytkusuchuButtonPushed(app, event)


               app.wynikL = (app.tab1(1) +app.tab1(2) +app.tab1(3))/3;
          
               app.wynikP = (app.tab2(1) +app.tab2(2) +app.tab2(3))/3;


            app.UbytekleweuchoEditField.Value = app.wynikL;
            
            app.UbytekpraweuchoEditField.Value = app.wynikP;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audiogram zobrazowanie progów słyszenia dzwięku')
            xlabel(app.UIAxes, 'Częstotliwość')
            ylabel(app.UIAxes, 'dB')
            zlabel(app.UIAxes, 'Hz')
            app.UIAxes.LineStyleOrder = {'-'; ':'; '-'};
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'Odtwarzanie dzwięku';
            app.TakButton = uibutton(app.Panel1, 'push');
            app.TakButton.ButtonPushedFcn = createCallbackFcn(app, @TakButtonPushed, true);
            app.TakButton.Text = 'Tak';
            app.OdtwrzdzwikButton = uibutton(app.Panel1, 'push');
            app.OdtwrzdzwikButton.ButtonPushedFcn = createCallbackFcn(app, @OdtwrzdzwikButtonPushed, true);
            app.OdtwrzdzwikButton.Text = 'Odtwórz dzwięk';
            app.label1 = uilabel(app.Panel1);
            app.label1.Text = 'Jeśli słyszysz dzwięk to wciśnij tak jeśli nie zwiększ wzmocnienie o następny próg?';
            app.CzstotliwoListBoxLabel = uilabel(app.Panel1);
            app.CzstotliwoListBoxLabel.Text = 'Częstotliwość';
            app.CzstotliwoListBox = uilistbox(app.Panel1);
            app.CzstotliwoListBox.Items = {'125', '250', '500', '1000', '1500', '2000', '3000', '4000'};
            app.CzstotliwoListBox.ItemsData = {'125', '250', '500', '1000', '1500', '2000', '3000', '4000'};
            app.CzstotliwoListBox.Value = '125';
            app.WzmocnienieListBoxLabel = uilabel(app.Panel1);
            app.WzmocnienieListBoxLabel.Text = 'Wzmocnienie';
            app.WzmocnienieListBox = uilistbox(app.Panel1);
            app.WzmocnienieListBox.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', ''};
            app.WzmocnienieListBox.Value = '10';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Słuchawka';
            app.ButtonGroup.Tag = 'BG';
            app.Lewa = uiradiobutton(app.ButtonGroup);
            app.Lewa.Tag = 'L';
            app.Lewa.Text = 'Lewa';
            app.Lewa.Value = true;
            app.Prawa = uiradiobutton(app.ButtonGroup);
            app.Prawa.Tag = 'P';
            app.Prawa.Text = 'Prawa';
            app.KalibracjaButton_2 = uibutton(app.UIFigure, 'push');
            app.KalibracjaButton_2.ButtonPushedFcn = createCallbackFcn(app, @KalibracjaButton_2Pushed, true);
            app.KalibracjaButton_2.Text = {'Kalibracja'; ''};
            app.UbytekleweuchoEditFieldLabel = uilabel(app.UIFigure);
            app.UbytekleweuchoEditFieldLabel.Text = 'Ubytek lewe ucho';
            app.UbytekleweuchoEditField = uieditfield(app.UIFigure, 'numeric');
            app.UbytekleweuchoEditField.ValueChangedFcn = createCallbackFcn(app, @UbytekleweuchoEditFieldValueChanged, true);
            app.UbytekpraweuchoEditFieldLabel = uilabel(app.UIFigure);
            app.UbytekpraweuchoEditFieldLabel.Text = 'Ubytek prawe ucho';
            app.UbytekpraweuchoEditField = uieditfield(app.UIFigure, 'numeric');
            app.wynikiubytkusuchuButton = uibutton(app.UIFigure, 'push');
            app.wynikiubytkusuchuButton.ButtonPushedFcn = createCallbackFcn(app, @wynikiubytkusuchuButtonPushed, true);
            app.wynikiubytkusuchuButton.Text = 'wyniki ubytku słuchu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end